classdef ratesAnalysisTool_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        Country2DropDown              matlab.ui.control.DropDown
        Country2DropDownLabel         matlab.ui.control.Label
        Country1DropDown              matlab.ui.control.DropDown
        Country1DropDownLabel         matlab.ui.control.Label
        UITableAnnuityValue           matlab.ui.control.Table
        UpdateplotsButton             matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        SelectFromKnobLabel           matlab.ui.control.Label
        SelectFromKnob                matlab.ui.control.DiscreteKnob
        RootDirectoryEditField        matlab.ui.control.EditField
        ApprootdirectoryLabel         matlab.ui.control.Label
        SelectadateDatePicker         matlab.ui.control.DatePicker
        SelectadateLabel              matlab.ui.control.Label
        UpdatePlotsButton             matlab.ui.control.Button
        LoadfilesforthenewdateButton  matlab.ui.control.Button
        SelectafileButton             matlab.ui.control.Button
    end

   % properties that are initialised at start up using the config file
    properties (Access = public)
        ValuationDate           
        startDateRates          
        inputFolderRates
        inputFileNameRates
        outputFolder
        showRatesImportProgress
        FDIstatus
        keyCountry1
        keyCountry2
        countries
        rateCurves
        rateScenarios
        mapCountryToCurveName
        annuityValue
        inputFolderLifeTables
        projectBasePath
        deployed
    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %set(gcf,'Visible','on');

            app.projectBasePath = fileparts(mfilename('fullpath'));
            disp(app.projectBasePath)
            run('ratesAnalysisToolConfig.m'); % Load configurations
            
            % Access the variables directly:
            % app.ValuationDate = ValuationDate;
            app.startDateRates = startDateRates;
            app.inputFolderRates = inputFolderRates;
            app.inputFileNameRates = inputFileNameRates;
            app.inputFolderLifeTables = inputFolderLifeTables;
           % app.outputFolder = outputFolder;
            app.showRatesImportProgress = showRatesImportProgress;
            
            app.keyCountry1 = keyCountry1; % set initial country 1
            app.keyCountry2 = keyCountry2; %set initial country 2
            display('Start to read rates: ' + showRatesImportProgress);
            [app.rateCurves,app.rateScenarios,app.mapCountryToCurveName] = marketdata.readRateFilesAndCreateRateCurves(app.inputFolderRates,app.inputFileNameRates,app.startDateRates, app.outputFolder,app.showRatesImportProgress);
            display(app.mapCountryToCurveName)
            app.Country2DropDown.Items = app.mapCountryToCurveName.keys;
            app.Country1DropDown.Items = app.mapCountryToCurveName.keys;
            display(app.Country1DropDown.Items);
            app.Country2DropDown.ItemsData = app.mapCountryToCurveName.keys;
            app.Country1DropDown.ItemsData = app.mapCountryToCurveName.keys;
            
            app.Country2DropDown.Value =keyCountry2;
            app.Country1DropDown.Value =keyCountry1;
            app.countries = [app.Country1DropDown.Value,app.Country2DropDown.Value];
            app.RootDirectoryEditField.Value = app.projectBasePath;
            
            %app.SelectFromKnob.Items = xlsFileNames;
            app.SelectFromKnob.ItemsData = xlsFileNames(1:4);
            app.SelectFromKnob.Value = inputFileNameRates;
            
            app.SelectadateDatePicker.Value = app.startDateRates;
            plotRates(app.UIAxes1,app.rateScenarios,app.rateCurves,app.keyCountry1,app.startDateRates,app.UIAxes2,app.keyCountry2)         
            
            tableData = createUIOutputTable(app.inputFolderLifeTables, app.rateCurves,app.countries,app.rateScenarios, app.startDateRates);
            app.UITableAnnuityValue.Data = tableData;
            app.UITableAnnuityValue.ColumnName = tableData.Properties.VariableNames;
            display('endstartup');
            display(app.UIFigure);

        end

        % Button pushed function: UpdatePlotsButton, UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)
           % Clear axes 1
            cla(app.UIAxes2);

            % Clear axes 2
            cla(app.UIAxes1); 

            app.keyCountry2 = app.Country2DropDown.Value;
            app.keyCountry1 = app.Country1DropDown.Value;
            
            plotRates(app.UIAxes1,app.rateScenarios,app.rateCurves,app.keyCountry1,app.startDateRates,app.UIAxes2,app.keyCountry2)
            app.countries = [app.keyCountry1,app.keyCountry2];
            
           %cla(app.UITableAnnuityValue);
            tableData = createUIOutputTable(app.inputFolderLifeTables, app.rateCurves,app.countries,app.rateScenarios, app.startDateRates);
            app.UITableAnnuityValue.Data = tableData;
            app.UITableAnnuityValue.ColumnName = tableData.Properties.VariableNames;

        end

        % Button pushed function: SelectafileButton
        function SelectafileButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.txt', 'Text Files (*.txt)'; ...
                                   '*.*',  'All Files (*.*)'}); 
            if isequal(filename, 0)  % User cancelled
                return; 
            end
            fullFilePath = fullfile(pathname, filename);
            app.inputFolderRates = pathname;
            app.inputFileNameRates = filename;
            app.startDateRates =  marketdata.extractDateEIOPAFile(filename);
            app.SelectadateDatePicker.Value = app.startDateRates;
        end

        % Button pushed function: LoadfilesforthenewdateButton
        function LoadfilesforthenewdateButtonPushed(app, event)
            display(app.UIFigure);
            [app.rateCurves,app.rateScenarios,app.mapCountryToCurveName] = marketdata.readRateFilesAndCreateRateCurves(app.inputFolderRates,app.inputFileNameRates,app.startDateRates, app.outputFolder,app.showRatesImportProgress);
            display(app.rateCurves)
        end

        % Callback function
        function keys(app, event)
            
        end

        % Value changed function: SelectadateDatePicker
        function selectADateButtonPushed(app, event)
            localStartDateRates = app.SelectadateDatePicker.Value;
            app.inputFileNameRates = 'EIOPA_RFR_'+string(year(localStartDateRates))+num2str(month(localStartDateRates), '%02d')+ string(day(localStartDateRates)) +'_Term_Structures.xlsx';
            app.startDateRates =localStartDateRates;
           
        end

        % Value changed function: Country2DropDown
        function Country2DropDownValueChanged(app, event)
            value = app.Country2DropDown.Value;
            
        end

        % Value changed function: Country1DropDown
        function Country1DropDownValueChanged(app, event)
           value = app.Country1DropDown.Value;
            
        end

        % Value changed function: SelectFromKnob
        function SelectFromKnobValueChanged(app, event)
            value = app.SelectFromKnob.Value;
            localStartDateRates = marketdata.extractDateEIOPAFile(value);
            app.startDateRates = localStartDateRates;
            app.SelectadateDatePicker.Value = app.startDateRates;
            app.inputFileNameRates = 'EIOPA_RFR_'+string(year(localStartDateRates))+num2str(month(localStartDateRates), '%02d')+ string(day(localStartDateRates)) +'_Term_Structures.xlsx';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LeftPanel.Title = 'Select rate curve files to load';
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.SelectafileButton = uibutton(app.GridLayout4, 'push');
            app.SelectafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectafileButtonPushed, true);
            app.SelectafileButton.Text = 'Select a file';
            app.LoadfilesforthenewdateButton = uibutton(app.GridLayout4, 'push');
            app.LoadfilesforthenewdateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesforthenewdateButtonPushed, true);
            app.LoadfilesforthenewdateButton.Text = {'"Load files'; 'for the new date"'};
            app.UpdatePlotsButton = uibutton(app.GridLayout4, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.SelectadateLabel = uilabel(app.GridLayout4);
            app.SelectadateLabel.Text = {'Select '; 'a '; 'date'};
            app.SelectadateDatePicker = uidatepicker(app.GridLayout4);
            app.SelectadateDatePicker.ValueChangedFcn = createCallbackFcn(app, @selectADateButtonPushed, true);
            app.SelectadateDatePicker.Placeholder = '30/09/2023';
            app.SelectadateDatePicker.Value = datetime([2023 9 30]);
            app.ApprootdirectoryLabel = uilabel(app.GridLayout4);
            app.ApprootdirectoryLabel.Text = 'Root Directory';
            app.RootDirectoryEditField = uieditfield(app.GridLayout4, 'text');
            app.SelectFromKnob = uiknob(app.GridLayout4, 'discrete');
            app.SelectFromKnob.Items = {'Sample Date 1', 'Sample Date 2', 'Sample Date 3', 'Sample Date 4'};
            app.SelectFromKnob.ValueChangedFcn = createCallbackFcn(app, @SelectFromKnobValueChanged, true);
            app.SelectFromKnob.Value = 'Sample Date 1';
            app.SelectFromKnobLabel = uilabel(app.GridLayout4);
            app.SelectFromKnobLabel.Text = 'Select   From';
            app.CenterPanel = uipanel(app.UIFigure);
            app.CenterPanel.Title = 'EIOPA rate curves for two countries';
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.CenterPanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.RightPanel = uipanel(app.UIFigure);
            app.RightPanel.Title = 'Select the countries to display';
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout3);
            app.UpdateplotsButton = uibutton(app.GridLayout2, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.UITableAnnuityValue = uitable(app.GridLayout2);
            app.UITableAnnuityValue.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableAnnuityValue.RowName = {};
            app.Country1DropDownLabel = uilabel(app.GridLayout2);
            app.Country1DropDownLabel.Text = 'Country 1';
            app.Country1DropDown = uidropdown(app.GridLayout2);
            app.Country1DropDown.Items = {'NZ', 'AU'};
            app.Country1DropDown.ItemsData = {'countryKeys'};
            app.Country1DropDown.ValueChangedFcn = createCallbackFcn(app, @Country1DropDownValueChanged, true);
            app.Country1DropDown.Value = 'countryKeys';
            app.Country2DropDownLabel = uilabel(app.GridLayout2);
            app.Country2DropDownLabel.Text = 'Country 2';
            app.Country2DropDown = uidropdown(app.GridLayout2);
            app.Country2DropDown.Items = {};
            app.Country2DropDown.ValueChangedFcn = createCallbackFcn(app, @Country2DropDownValueChanged, true);
            app.Country2DropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ratesAnalysisTool_converted

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end