classdef ratesAnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectFromKnobLabel           matlab.ui.control.Label
        SelectFromKnob                matlab.ui.control.DiscreteKnob
        RootDirectoryEditField        matlab.ui.control.EditField
        ApprootdirectoryLabel         matlab.ui.control.Label
        UpdatePlotsButton             matlab.ui.control.Button
        SelectadateDatePicker         matlab.ui.control.DatePicker
        SelectadateLabel              matlab.ui.control.Label
        LoadfilesforthenewdateButton  matlab.ui.control.Button
        SelectafileButton             matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        Country2DropDown              matlab.ui.control.DropDown
        Country2DropDownLabel         matlab.ui.control.Label
        Country1DropDown              matlab.ui.control.DropDown
        Country1DropDownLabel         matlab.ui.control.Label
        UITableAnnuityValue           matlab.ui.control.Table
        UpdateplotsButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

   % properties that are initialised at start up using the config file
    properties (Access = public)
        ValuationDate           
        startDateRates          
        inputFolderRates
        inputFileNameRates
        inputFolderLifeTables
        outputFolder
        showRatesImportProgress
        FDIstatus
        keyCountry1
        keyCountry2
        rateCurves
        rateScenarios
        mapCountryToCurveName
        annuityValue
        projectBasePath
        deployed
    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %set(gcf,'Visible','on');

            app.projectBasePath = fileparts(mfilename('fullpath'));
            configFile = fullfile(app.projectBasePath,'config.m');
            run(configFile); % Load configurations
            
            % Access the variables directly:
            % app.ValuationDate = ValuationDate;
            app.startDateRates = startDateRates;
            app.inputFolderRates = inputFolderRates;
            app.inputFileNameRates = inputFileNameRates;
            app.inputFolderLifeTables = inputFolderLifeTables;
           % app.outputFolder = outputFolder;
            app.showRatesImportProgress = showRatesImportProgress;
            app.FDIstatus = FDIstatus;
            app.keyCountry1 = keyCountry1; % set initial country 1
            app.keyCountry2 = keyCountry2; %set initial country 2
            
            [app.rateCurves,app.rateScenarios,app.mapCountryToCurveName] = readRateFilesAndCreateRateCurves(app.inputFolderRates,app.inputFileNameRates,app.startDateRates, app.outputFolder,app.showRatesImportProgress);
            display(app.mapCountryToCurveName)
            app.Country2DropDown.Items = app.mapCountryToCurveName.keys;
            app.Country1DropDown.Items = app.mapCountryToCurveName.keys;
            display(app.Country1DropDown.Items);
            app.Country2DropDown.ItemsData = app.mapCountryToCurveName.keys;
            app.Country1DropDown.ItemsData = app.mapCountryToCurveName.keys;
            
            app.Country2DropDown.Value =keyCountry2;
            app.Country1DropDown.Value =keyCountry1;
            app.RootDirectoryEditField.Value = app.projectBasePath;
            
            %app.SelectFromKnob.Items = xlsFileNames;
            app.SelectFromKnob.ItemsData = xlsFileNames(1:4);
            app.SelectFromKnob.Value = inputFileNameRates;
            
            app.SelectadateDatePicker.Value = app.startDateRates;
            plotRates(app.UIAxes1,app.rateScenarios,app.rateCurves,app.keyCountry1,app.startDateRates,app.UIAxes2,app.keyCountry2)         
            
            
            annuityValues = lifetimeAnnuityValue(app.inputFolderLifeTables, app.rateCurves,app.keyCountry1,app.rateScenarios, app.startDateRates, 60, 'M', 0,100,100000,0.03);
            tableData = table(app.keyCountry1, annuityValues.pv1(1), annuityValues.pv2(1), 'VariableNames', {'Country', 'Life expectancy $m', 'Lifetime Value $m'})
            app.UITableAnnuityValue.Data = tableData;
            app.UITableAnnuityValue.ColumnName = tableData.Properties.VariableNames;
            display('endstartup');
            display(app.UIFigure);

        end

        % Button pushed function: UpdatePlotsButton, UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)
           % Clear axes 1
            cla(app.UIAxes2);

            % Clear axes 2
            cla(app.UIAxes1); 

            app.keyCountry2 = app.Country2DropDown.Value;
            app.keyCountry1 = app.Country1DropDown.Value;
            
            plotRates(app.UIAxes1,app.rateScenarios,app.rateCurves,app.keyCountry1,app.startDateRates,app.UIAxes2,app.keyCountry2)         

        end

        % Button pushed function: SelectafileButton
        function SelectafileButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.txt', 'Text Files (*.txt)'; ...
                                   '*.*',  'All Files (*.*)'}); 
            if isequal(filename, 0)  % User cancelled
                return; 
            end
            fullFilePath = fullfile(pathname, filename);
            app.inputFolderRates = pathname;
            app.inputFileNameRates = filename;
            app.startDateRates =  extractDateEIOPAFile(filename);
            app.SelectadateDatePicker.Value = app.startDateRates;
        end

        % Button pushed function: LoadfilesforthenewdateButton
        function LoadfilesforthenewdateButtonPushed(app, event)
            [app.rateCurves,app.rateScenarios,app.mapCountryToCurveName] = readRateFilesAndCreateRateCurves(app.inputFolderRates,app.inputFileNameRates,app.startDateRates, app.outputFolder,app.showRatesImportProgress);
        end

        % Callback function
        function keys(app, event)
            
        end

        % Value changed function: SelectadateDatePicker
        function selectADateButtonPushed(app, event)
            localStartDateRates = app.SelectadateDatePicker.Value;
            app.inputFileNameRates = 'EIOPA_RFR_'+string(year(localStartDateRates))+num2str(month(localStartDateRates), '%02d')+ string(day(localStartDateRates)) +'_Term_Structures.xlsx';
            app.startDateRates =localStartDateRates;
           
        end

        % Value changed function: Country2DropDown
        function Country2DropDownValueChanged(app, event)
            value = app.Country2DropDown.Value;
            
        end

        % Value changed function: Country1DropDown
        function Country1DropDownValueChanged(app, event)
           value = app.Country1DropDown.Value;
            
        end

        % Value changed function: SelectFromKnob
        function SelectFromKnobValueChanged(app, event)
            value = app.SelectFromKnob.Value;
            app.startDateRates = marketdata.extractDateEIOPAFile(value);
            app.SelectadateDatePicker.Value = app.startDateRates;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {268, '1x', 291};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Select rate curve files to load';
            app.SelectafileButton = uibutton(app.LeftPanel, 'push');
            app.SelectafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectafileButtonPushed, true);
            app.SelectafileButton.Text = 'Select a file';
            app.LoadfilesforthenewdateButton = uibutton(app.LeftPanel, 'push');
            app.LoadfilesforthenewdateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesforthenewdateButtonPushed, true);
            app.LoadfilesforthenewdateButton.Text = {'"Load files'; 'for the new date"'};
            app.SelectadateLabel = uilabel(app.LeftPanel);
            app.SelectadateLabel.Text = {'Select '; 'a '; 'date'};
            app.SelectadateDatePicker = uidatepicker(app.LeftPanel);
            app.SelectadateDatePicker.ValueChangedFcn = createCallbackFcn(app, @selectADateButtonPushed, true);
            app.SelectadateDatePicker.Placeholder = '30/09/2023';
            app.SelectadateDatePicker.Value = datetime([2023 9 30]);
            app.UpdatePlotsButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.ApprootdirectoryLabel = uilabel(app.LeftPanel);
            app.ApprootdirectoryLabel.Text = 'Root Directory';
            app.RootDirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectFromKnob = uiknob(app.LeftPanel, 'discrete');
            app.SelectFromKnob.Items = {'Sample Date 1', 'Sample Date 2', 'Sample Date 3', 'Sample Date 4'};
            app.SelectFromKnob.ValueChangedFcn = createCallbackFcn(app, @SelectFromKnobValueChanged, true);
            app.SelectFromKnob.Value = 'Sample Date 1';
            app.SelectFromKnobLabel = uilabel(app.LeftPanel);
            app.SelectFromKnobLabel.Text = 'Select   From';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'EIOPA rate curves for two countries';
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.CenterPanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Select the countries to display';
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout3);
            app.UpdateplotsButton = uibutton(app.GridLayout2, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.UITableAnnuityValue = uitable(app.GridLayout2);
            app.UITableAnnuityValue.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableAnnuityValue.RowName = {};
            app.Country1DropDownLabel = uilabel(app.GridLayout2);
            app.Country1DropDownLabel.Text = 'Country 1';
            app.Country1DropDown = uidropdown(app.GridLayout2);
            app.Country1DropDown.Items = {'NZ', 'AU'};
            app.Country1DropDown.ItemsData = {'countryKeys'};
            app.Country1DropDown.ValueChangedFcn = createCallbackFcn(app, @Country1DropDownValueChanged, true);
            app.Country1DropDown.Value = 'countryKeys';
            app.Country2DropDownLabel = uilabel(app.GridLayout2);
            app.Country2DropDownLabel.Text = 'Country 2';
            app.Country2DropDown = uidropdown(app.GridLayout2);
            app.Country2DropDown.Items = {};
            app.Country2DropDown.ValueChangedFcn = createCallbackFcn(app, @Country2DropDownValueChanged, true);
            app.Country2DropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ratesAnalysisTool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end